classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        Cw1_ySlider       matlab.ui.control.Slider
        Cw1_ySliderLabel  matlab.ui.control.Label
        Cw2_ySlider       matlab.ui.control.Slider
        Cw2_ySliderLabel  matlab.ui.control.Label
        T_Slider          matlab.ui.control.Slider
        T_Label           matlab.ui.control.Label
        StartButton       matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Mymodel = 'test_model';
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load_system(app.Mymodel);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            my_sim_result=sim(app.Mymodel);

            plot(app.UIAxes, my_sim_result.simout_z.Time, my_sim_result.simout_z.Data);
            plot(app.UIAxes2, my_sim_result.simout_vz.Time, my_sim_result.simout_vz.Data);
        end

        % Value changed function: T_Slider
        function T_SliderValueChanged(app, event)
            value = app.T_Slider.Value;

            den_str = mat2str([value, 1]);
            set_param([app.Mymodel '/Transfer Fcn5'] , 'Denominator', den_str);
        end

        % Value changed function: Cw1_ySlider
        function Cw1_ySliderValueChanged(app, event)
            value = app.Cw1_ySlider.Value;
            set_param([app.Mymodel '/Gain33'] , 'Gain', num2str(value));
        end

        % Value changed function: Cw2_ySlider
        function Cw2_ySliderValueChanged(app, event)
            value = app.Cw2_ySlider.Value;
            set_param([app.Mymodel '/Gain32'] , 'Gain', num2str(value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Зависимость скорости от времени')
            xlabel(app.UIAxes2, 't, сек')
            ylabel(app.UIAxes2, 'Vy, м/с')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Зависимость координаты от времени')
            xlabel(app.UIAxes, 't, сек')
            ylabel(app.UIAxes, 'Y, м')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Запуск';
            app.T_Label = uilabel(app.GridLayout);
            app.T_Label.Text = 'T_дв, сек';
            app.T_Slider = uislider(app.GridLayout);
            app.T_Slider.ValueChangedFcn = createCallbackFcn(app, @T_SliderValueChanged, true);
            app.Cw2_ySliderLabel = uilabel(app.GridLayout);
            app.Cw2_ySliderLabel.Text = 'Cw2_y';
            app.Cw2_ySlider = uislider(app.GridLayout);
            app.Cw2_ySlider.ValueChangedFcn = createCallbackFcn(app, @Cw2_ySliderValueChanged, true);
            app.Cw1_ySliderLabel = uilabel(app.GridLayout);
            app.Cw1_ySliderLabel.Text = 'Cw1_y';
            app.Cw1_ySlider = uislider(app.GridLayout);
            app.Cw1_ySlider.ValueChangedFcn = createCallbackFcn(app, @Cw1_ySliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end